classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TEMZLEButton               matlab.ui.control.Button
        PATLAYICITESPTEDLDLabel    matlab.ui.control.Label
        PATLAYICITESPTEDLMEDLabel  matlab.ui.control.Label
        Green_Lamp                 matlab.ui.control.Lamp
        Red_Lamp                   matlab.ui.control.Lamp
        ANALZETButton              matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        DOSYAAButton               matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    methods (Access = private)
        function detected(app)
            app.Red_Lamp.Color = "red";
            app.Green_Lamp.Color = "#cccccc";
        end
        function not_detected(app)
            app.Red_Lamp.Color = "#cccccc";
            app.Green_Lamp.Color = "green";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Red_Lamp.Color = "#cccccc";
            app.Green_Lamp.Color = "#cccccc";
        end

        % Button pushed function: DOSYAAButton
        function DOSYAAButtonPushed(app, event)
            [file, path] = uigetfile({'*';'*.png';'*.jpg';'*.jpeg'}, ...
                "Dosya Seç");
            fullPath = path + "" + file;
            app.EditField.Value = fullPath;
            app.Image.ImageSource = fullPath;
        end

        % Button pushed function: ANALZETButton
        function ANALZETButtonPushed(app, event)
            %rng(0,'twister');
            r = randi([0 10],1,2);
            if mod(r, 2) == 0
                detected(app);
            else
                not_detected(app);
            end
        end

        % Button pushed function: TEMZLEButton
        function TEMZLEButtonPushed(app, event)
            app.Red_Lamp.Color = "#cccccc";
            app.Green_Lamp.Color = "#cccccc";
            app.Image.ImageSource = "";
            app.EditField.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Topluluk ve Derin Öğrenme Teknikleri Kullanılarak X-Ray Görüntülerinden Patlayıcı Devre Tespiti';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.DOSYAAButton = uibutton(app.GridLayout, 'push');
            app.DOSYAAButton.ButtonPushedFcn = createCallbackFcn(app, @DOSYAAButtonPushed, true);
            app.DOSYAAButton.Text = 'DOSYA AÇ';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Dosya Yolu';
            app.ANALZETButton = uibutton(app.GridLayout, 'push');
            app.ANALZETButton.ButtonPushedFcn = createCallbackFcn(app, @ANALZETButtonPushed, true);
            app.ANALZETButton.Text = 'ANALİZ ET';
            app.Red_Lamp = uilamp(app.GridLayout);
            app.Green_Lamp = uilamp(app.GridLayout);
            app.PATLAYICITESPTEDLMEDLabel = uilabel(app.GridLayout);
            app.PATLAYICITESPTEDLMEDLabel.Text = 'PATLAYICI TESPİT EDİLMEDİ';
            app.PATLAYICITESPTEDLDLabel = uilabel(app.GridLayout);
            app.PATLAYICITESPTEDLDLabel.Text = 'PATLAYICI TESPİT EDİLDİ';
            app.TEMZLEButton = uibutton(app.GridLayout, 'push');
            app.TEMZLEButton.ButtonPushedFcn = createCallbackFcn(app, @TEMZLEButtonPushed, true);
            app.TEMZLEButton.Text = 'TEMİZLE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end